classdef PathOptimization_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        axesMain                 matlab.ui.control.UIAxes
        panelRunControls         matlab.ui.container.Panel
        methodMenu               matlab.ui.control.DropDown
        methodLabel              matlab.ui.control.Label
        startOptimPanel          matlab.ui.container.Panel
        optimizeGlobalButton     matlab.ui.control.Button
        optimizeLocalButton      matlab.ui.control.Button
        AirspeedLabel            matlab.ui.control.Label
        planeSpeedEdit           matlab.ui.control.NumericEditField
        PointsLabel              matlab.ui.control.Label
        numPointsEdit            matlab.ui.control.NumericEditField
        optimalResultsPanel      matlab.ui.container.Panel
        optTimeText              matlab.ui.control.Label
        optDistanceText          matlab.ui.control.Label
        optSpeedText             matlab.ui.control.Label
        manualResultsPanel       matlab.ui.container.Panel
        manualSpeedText          matlab.ui.control.Label
        manualDistanceText       matlab.ui.control.Label
        manualTimeText           matlab.ui.control.Label
        windFieldPanel           matlab.ui.container.Panel
        wewWindFieldLabel        matlab.ui.control.Button
        fieldSizeLabel           matlab.ui.control.Label
        RandomSeedLabel          matlab.ui.control.Label
        seedEdit                 matlab.ui.control.NumericEditField
        XLabel                   matlab.ui.control.Label
        szXEdit                  matlab.ui.control.NumericEditField
        YLabel                   matlab.ui.control.Label
        szYEdit                  matlab.ui.control.NumericEditField
        WindFieldRoughnessLabel  matlab.ui.control.Label
        windFieldRoughnessEdit   matlab.ui.control.NumericEditField
    end

    
    % Copyright 2012-2020 The MathWorks, Inc.
    
    properties (Access = public)
        szX % Size of Wind Field X direction
        szY % Size of Wind Field Y direction
        iX % x Wind Field
        iY % y Wind Field
        
        lineInteractiveCurve
        lineOptimalCurve
        IntegrationFineness % Number of interpolated points in lines
        ctrlpts % Current manual waypoints
        pInitial % Straight line path waypoints  
    end
          
    methods (Access = private)
        function buttonDownOnLine(app,src,~)
            % When we click on a control point, we set up the dragging action here.
            % We modify the figure's windowbuttonmotionfcn.
            
            method = app.methodMenu.Value;
            planeSpeed = app.planeSpeedEdit.Value;
            
            hp = src;
            set(hp,'MarkerFaceColor','k');
            set(app.figure1,'WindowButtonupFcn',{@app.resetDrag,hp})
            set(app.figure1,'WindowButtonMotionFcn',{@app.onDrag,hp,method,planeSpeed})
            
        end
        
        function onDrag(app,~,~,hp,method,planeSpeed)
            % This function drags the control point to the new location
            % and updates the time calculation and displays it.
            
            cp = get(app.axesMain,'CurrentPoint');
            % Don't let the control points go outside the box:
            cp = max(cp,0);
            set(hp,'XData',min(cp(1),app.szX),'YData',min(cp(3),app.szY));
            
            calculateManualValues(app,method,planeSpeed);
        end
        
        function resetDrag(app,~,~,hp)
            % Turn off dragging when the mouse button is released
            try set(hp,'MarkerFaceColor',[0.5 0.5 1]); 
            catch
            end %Sometimes this hp is not valid
            set(app.figure1,'WindowButtonMotionFcn','');
        end
        
        function calculateManualValues(app,method,planeSpeed)
            % This function updates the manual line and also calls the time calculation
            % routine.
            
            p = app.pInitial;
            p(2:end-1,:) = cell2mat(get(app.ctrlpts(ishghandle(app.ctrlpts)),{'xdata','ydata'}));
            XYdata = app.pointsToCurve(p,method,app.IntegrationFineness);
            set(app.lineInteractiveCurve,'Xdata',XYdata(:,1),'Ydata',XYdata(:,2));
            [t_interactive,dist_interactive] = ...
                app.calculateTime(XYdata,app.iX,app.iY,planeSpeed,app.szX,app.szY,method,...
                app.IntegrationFineness,app.pInitial,'interactive');
            
            updateManualText(app,dist_interactive/t_interactive,dist_interactive,t_interactive);
            drawnow;
        end
        
        function makeInteractiveLine(app)
            % This function sets up the interactive manual path.
            npts = app.numPointsEdit.Value;
            
            % Delete the current line if it exists
            if ishandle(app.lineInteractiveCurve)
                delete(app.lineInteractiveCurve);
                delete(app.ctrlpts(ishghandle(app.ctrlpts)));
            end
            
            p = linspace(0,app.szX,npts+2)'; % Linearly spaced in X
            p = [p 0*p+app.szY/2]; % Halfway up the figure in Y
            app.pInitial = p;
            
            hold(app.axesMain,'on');
            
            % Interpolate and draw the line
            method = app.methodMenu.Value;
            XYdata = app.pointsToCurve(p,method,app.IntegrationFineness);
            app.lineInteractiveCurve = plot(app.axesMain,XYdata(:,1),XYdata(:,2),'Color',[.5 .5 1],'LineWidth',2);
            set(app.lineInteractiveCurve,'HitTest','off'); % We don't need this to be selectable
            
            % These will be the draggable control points
            app.ctrlpts = gobjects(size(p,1)-1);
            for n = 2:(size(p,1)-1)
                app.ctrlpts(n) = plot(app.axesMain,p(n,1),p(n,2),'o','Color',[.5 .5 1],'MarkerFaceColor',[.5 .5 1]);
            end
            % End points are not draggable
            plot(app.axesMain,p([1 end],1),p([1 end],2),'o','Color',[.5 .5 1],'MarkerFaceColor',[.5 .5 1]);       
        
            % Do the calculation for the straight line path
            planeSpeed = app.planeSpeedEdit.Value;
            [t_straightLine,dist_straightLine] = ...
                app.calculateTime(XYdata,app.iX,app.iY,planeSpeed,app.szX,app.szY,'linear',...
                app.IntegrationFineness,app.pInitial,'initial');
            updateManualText(app,dist_straightLine/t_straightLine,dist_straightLine,t_straightLine);
            title(app.axesMain,sprintf('Straight Line Time: %d hours, %.1f minutes\n',...
                floor(t_straightLine),rem(t_straightLine,1)*60));
            
            % Set the control points' button down function (make them draggable)            
            set(app.ctrlpts(ishghandle(app.ctrlpts)),'ButtonDownFcn',@app.buttonDownOnLine);
        end
        
        function Iwind = makeRandomWindField(~, windFineness, szX, szY)
            % This attempts to make a random vector field of wind
            
            N = 50; % Various parameters used in generating a random 'smooth' matrix
            NL = 40;
            NP = 500;
            rx = randn(NL,N);
            rx = interpft(rx,NP);
            ry = randn(NL,N);
            ry = interpft(ry,NP);
            I = (rx*ry');
            
            [xgi,ygi] = meshgrid(linspace(1,2 + 498*windFineness,szX+1),linspace(1,2 + 498*windFineness,szY+1));
            Iwind = interp2(1:500,1:500,I,xgi,ygi);
        end              
        
        function updateManualText(app, spd, d, t)
            % Updates the text fields for the Manual path line.
            if isempty(spd)
                app.manualSpeedText.Text = sprintf('Average Speed: ');
                app.manualDistanceText.Text = sprintf('Distance: ');
                app.manualTimeText.Text = sprintf('Time: ');
            else
                app.manualSpeedText.Text = sprintf('Average Speed: %.3f kph',100*spd);
                app.manualDistanceText.Text = sprintf('Distance: %.3f km',100*d);
                app.manualTimeText.Text = sprintf('Time: %d hours, %.1f minutes\n',...
                    floor(t),rem(t,1)*60); %convert Hours --> Hours and Minutes...
            end
            drawnow;
        end
        
        function updateOptText(app, spd, d, t)
            % Updates the text fields for the Optimal path line.
            if isempty(spd)
                app.optSpeedText.Text = sprintf('Average Speed: ');
                app.optDistanceText.Text = sprintf('Distance: ');
                app.optTimeText.Text = sprintf('Time: ');            
            else
                app.optSpeedText.Text = sprintf('Average Speed: %.3f kph',100*spd);
                app.optDistanceText.Text = sprintf('Distance: %.3f km',100*d);
                app.optTimeText.Text = sprintf('Time: %d hours, %.1f minutes\n',...
                    floor(t),rem(t,1)*60);
            end
            drawnow;
        end
   
    end
    
    methods(Static)
        function XYdata = pointsToCurve(p, method, fineness)
            % Interpolate the curve based on the control points and method
            % The number of points interpolated is defined in 'fineness'
            nP = size(p,1);
            XYdata = [interp1(1:nP,p(:,1),linspace(1,nP,fineness)',method,'extrap') ...
                interp1(1:nP,p(:,2),linspace(1,nP,fineness)',method,'extrap')];
        end
        
        function stop = outfun(x, optimValues, ~, app, method)
            % This is for interactive viewing of the optimization process. This will be
            % called to update the graph when doing local optimization. This is not
            % used during global optimization.
            
            % Only run this function once every 10 times, so we don't slow down the
            % optimization too much
            persistent ctr
            stop = false;
            if isempty(ctr)
                ctr = 0;
            end
            ctr = ctr+1;
            if mod(ctr,10)~= 1
                return
            end
            stop = false;
            
            % Redraw the optimization solver's line based on the current point
            popt = x;
            p = [0 app.szY/2; reshape(popt, 2,[])'; app.szX app.szY/2];
            delete(findall(app.axesMain,'tag','OptimalLine'));
            hold(app.axesMain,'on');
            
            XYdata = app.pointsToCurve(p,method,app.IntegrationFineness);
            app.lineOptimalCurve = plot(app.axesMain,XYdata(:,1),XYdata(:,2),'k','LineWidth',2,'tag','OptimalLine');
            set(app.lineOptimalCurve,'HitTest','off');
            set(0,'ShowHiddenHandles','on');
            hp = gobjects(size(p,1)-1,1);
            for n = 2:(size(p,1)-1)
                hp(n) = plot(app.axesMain,p(n,1),p(n,2),'ko');
            end
            set(hp(2:end),'tag','OptimalLine','HitTest','off');
            
            XYdata(:,1) = min(XYdata(:,1),app.szX);
            XYdata(:,1) = max(XYdata(:,1),0);
            XYdata(:,2) = min(XYdata(:,2),app.szY);
            XYdata(:,2) = max(XYdata(:,2),0);
            dP = diff(XYdata);
            dist_opt = sum(sqrt(sum(dP.^2,2)));
            t_opt = optimValues.fval;
            updateOptText(app,dist_opt/t_opt,dist_opt,t_opt);
            drawnow;
        end
        
        function varargout = calculateTime(X, iX, iY, planeSpeed, szX, szY, method, fineness, p0, caller)
            % This is the main function that actually calculates the line integral
            % along the path.
            %
            
            % If we are called from the optimization routine (caller = 'optimizer')
            % then we need to interpolate the fine path from the input control points.
            if strcmp(caller,'optimizer')
                p = [p0(1,:); reshape(X, 2,[])'; p0(end,:)];
                nP = size(p,1);
                plist = [interp1(1:nP,p(:,1),linspace(1,nP,fineness)',method,'extrap')...
                    interp1(1:nP,p(:,2),linspace(1,nP,fineness)',method,'extrap')];
            else %If we are in interactive mode, then we already have the fine point list
                plist = X;
            end
            
            % Do not let the path go out of the box
            plist(:,1) = min(plist(:,1),szX);
            plist(:,1) = max(plist(:,1),0);
            plist(:,2) = min(plist(:,2),szY);
            plist(:,2) = max(plist(:,2),0);
            
            dP = diff(plist);
            
            % We use a faster version of interp2 to interpolate the wind vector field
            % at all the points in plist.
            %Vwind_path = [interp2(iX,plist(1:end-1,1)+1,plist(1:end-1,2)+1,'linear') interp2(iY,plist(1:end-1,1)+1,plist(1:end-1,2)+1,'linear')];
            Vwind_path = [faster2dinterp(iX,plist(1:end-1,:)+1) ...
                faster2dinterp(iY,plist(1:end-1,:)+1)];
            
            % Dot product the wind (Vwind_path) with the direction vector (dP) to get
            % the tailwind/headwind contribution
            V_add = (sum(Vwind_path.*dP,2))./sqrt(sum(dP.^2,2));
            
            dx = sqrt(sum(dP.^2,2)); %dx is the length of each subinterval in plist
            dT = dx./(planeSpeed+V_add);  %dT = dP/dV
            totalTime = 100*sum(dT);
            % If the windspeed exceeds the airplane's airspeed, then we will get
            % meaningless negative values.
            if any(dT <= 0)
                totalTime = Inf;
            end
            varargout{1} = totalTime;
            
            % The optimizer only has one output, and will not need to output the
            % path distance as we do here
            if nargout > 1
                totalDist = sum(dx);
                varargout{2} = totalDist;
                drawnow;
            end
            
            function OUT = faster2dinterp(R, p)
                % A helper function to do faster 2d interpolation on the wind vector field
                p = p(:,[2 1]);
                Aloc = floor(p);
                localXY = p - Aloc;
                Bloc = Aloc;
                Bloc(:,1) = min(Bloc(:,1)+1,size(R,1));
                Cloc = Aloc;
                Cloc(:,2) = min(Cloc(:,2)+1,size(R,2));
                Dloc = [min(Aloc(:,1),size(R,1)-1) min(Aloc(:,2),size(R,2)-1)]+1;
                
                W1 = R(Aloc(:,1) + size(R,1)*Aloc(:,2)-size(R,1));
                W2 = R(Bloc(:,1) + size(R,1)*Bloc(:,2)-size(R,1))-W1;
                W3 = R(Cloc(:,1) + size(R,1)*Cloc(:,2)-size(R,1))-W1;
                W4 = R(Dloc(:,1) + size(R,1)*Dloc(:,2)-size(R,1))-W1-W2-W3;
                OUT = W1 + W2 .*localXY(:,1) + W3.*localXY(:,2) + W4.*localXY(:,1).*localXY(:,2);
            end
        end
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function PathOptimizationDemo_OpeningFcn(app)
           
            %Initialize the GUI with the New Wind Field button callback
            newWindFieldLabel_Callback(app)
        end

        % Value changed function: numPointsEdit
        function numPointsEdit_Callback(app, event)
            % When the number of points are changed, reset the interactive line
            try
                makeInteractiveLine(app);
            catch %If there is bad input, reset it to 5 control points.
                app.numPointsEdit.Value = 5;
                makeInteractiveLine(app);
            end
            
            updateOptText(app,[],[],[]); %Clear the 'optimal' box
            delete(findall(app.axesMain,'tag','OptimalLine'));
        end

        % Value changed function: planeSpeedEdit
        function planeSpeedEdit_Callback(app, event)
            % When the Plane Airspeed is changed, this updates the plot.
            
            method = app.methodMenu.Value;
            planeSpeed = app.planeSpeedEdit.Value;
            
            calculateManualValues(app,method,planeSpeed)
            p = app.pInitial(2:end-1,:)';
            t_straightLine = app.calculateTime(p(:),app.iX,app.iY,planeSpeed,app.szX,app.szY,method,...
                app.IntegrationFineness,app.pInitial,'optimizer');
            
            title(app.axesMain,sprintf('Straight Line Time: %d hours, %.1f minutes\n',floor(t_straightLine),rem(t_straightLine,1)*60));

            updateOptText(app,[],[],[]); %Clear the 'optimal' box
            delete(findall(app.axesMain,'tag','OptimalLine'));
        end

        % Value changed function: methodMenu
        function methodMenu_Callback(app, event)
            % When the Interpolation method is changed, this updates the plot.
            
            method = app.methodMenu.Value;
            planeSpeed = app.planeSpeedEdit.Value;
            
            calculateManualValues(app,method,planeSpeed)
            
            updateOptText(app,[],[],[]); %Clear the 'optimal' box
            delete(findall(app.axesMain,'tag','OptimalLine'));
        end

        % Button pushed function: wewWindFieldLabel
        function newWindFieldLabel_Callback(app, event)
            
            updateOptText(app,[],[],[]); %Clear the 'optimal' box
            
            seed = app.seedEdit.Value;
            if isfinite(seed)
                rng(typecast(single(seed),'uint32'));
            else
                rng('shuffle');
            end
            
            app.szX = app.szXEdit.Value;
            app.szY = app.szYEdit.Value;
            
            windFineness = app.windFieldRoughnessEdit.Value;
            
            % Make the wind fields
            app.iX = makeRandomWindField(app,windFineness,app.szX,app.szY);
            app.iX = app.iX/(max(abs(app.iX(:)))) * (50+150*rand);
            app.iY = makeRandomWindField(app,windFineness,app.szX,app.szY);
            app.iY = app.iY/(max(abs(app.iY(:)))) * (50+150*rand);
            
            [Xg,Yg] = meshgrid(0:app.szX,0:app.szY);
            % Penalty represents the wind component in the direction of the end point
            L = (sqrt((Xg-app.szX).^2 + (Yg-app.szY/2).^2));
            Penalty = (app.szX-Xg)./L .* app.iX +  app.iY.*(app.szY/2-Yg)./L;
            Penalty(~isfinite(Penalty)) = nan;
            
            delete(get(app.axesMain,'Children'));  
            app.axesMain.Position = [25 138 500 418]; 
            IM = imagesc(app.axesMain,Penalty); % This will be the background for the vector field
            app.axesMain.YDir = 'normal'; % imagesc reverses the y-axis
            
            IM.XData = [0 app.szX];
            IM.YData = [0 app.szY];          

            app.axesMain.XLim = [0 app.szX];
            app.axesMain.YLim = [0 app.szY];  
            xlabel(app.axesMain,'Units = 100 [km]');
            
            hold(app.axesMain,'on');
            colormap(app.axesMain,interp1([0,1,2],[1 0 0; 1 1 1; 0 1 0],0:0.01:2));
            caxis(app.axesMain,max(abs(Penalty(:)))*[-1 1]);
            
            quiver(app.axesMain,Xg,Yg,app.iX,app.iY,'k'); % Draw the vector field
            
            h_colorbar = colorbar(app.axesMain);
            title(h_colorbar,'Tailwind (km/h)')
            
            app.axesMain.DataAspectRatio = [1 1 1];
            
            plot(app.axesMain,[0 app.szX],[app.szY app.szY]/2,'k.','MarkerSize',30);
            drawnow;
            
            app.lineInteractiveCurve = [];
            app.lineOptimalCurve = [];
            app.ctrlpts = [];
            app.IntegrationFineness = 101; % Number of steps in the line integral
            
            % Create the blue interactive line, and do the straight line path
            makeInteractiveLine(app);
        end

        % Button pushed function: optimizeLocalButton
        function optimizeLocalButton_Callback(app, event)
            % This function carries out a local optimization with FMINCON starting at
            % the current location of the control points for the interactive line.
            
            method = app.methodMenu.Value;
            planeSpeed = app.planeSpeedEdit.Value;
            npts = app.numPointsEdit.Value;
            
            options = optimoptions('fmincon');
            options.Algorithm = 'sqp';
            options.MaxFunctionEvaluations = npts*500;
            options.Display = 'iter';    
            options.UseParallel = false;
            options.OutputFcn = @(x, optimValues, state) app.outfun(x,optimValues,state,app,method);
            options.OptimalityTolerance = 5e-6;            
            options.StepTolerance = 5e-6;
            
            % Get the X and Y data from the control points.
            p0 = cell2mat(get(app.ctrlpts(ishghandle(app.ctrlpts)),{'XData','YData'}));
            p0 = reshape(transpose(p0),1,[])'; % Make it a column vector
            
            % Call FMINCON with calculateTime as the objective function
            
            objfun = @(X)app.calculateTime(X,app.iX,app.iY,planeSpeed,app.szX,app.szY,method,...
                app.IntegrationFineness,app.pInitial,'optimizer');
            
            popt = fmincon(objfun,p0(:),[],[],[],[],...
                p0*0,repmat([app.szX; app.szY],size(p0,1)/2,1),[],options);
            
            p = [0 app.szY/2; reshape(popt, 2,[])'; app.szX app.szY/2]; % Reshape back to Nx2
            
            delete(findall(app.axesMain,'tag','OptimalLine'));
            % Draw the optimal line with waypoints and update the 'optimal' info box
            hold(app.axesMain,'on');
            XYdata = app.pointsToCurve(p,method,app.IntegrationFineness);
            app.lineOptimalCurve = plot(app.axesMain,XYdata(:,1),XYdata(:,2),'k','LineWidth',2,'tag','OptimalLine');
            set(app.lineOptimalCurve,'HitTest','off');
            
            hp = gobjects(size(p,1)-1,1);
            for n = 2:(size(p,1)-1)
                hp(n) = plot(app.axesMain,p(n,1),p(n,2),'ko');
            end
            set(hp(2:end),'tag','OptimalLine','HitTest','off');
            
            [t_opt,dist_opt] = ...
                app.calculateTime(XYdata,app.iX,app.iY,planeSpeed,app.szX,app.szY,method,...
                app.IntegrationFineness,app.pInitial,'interactive');
            updateOptText(app,dist_opt/t_opt,dist_opt,t_opt);
        end

        % Button pushed function: optimizeGlobalButton
        function optimizeGlobalButton_Callback(app, event)
            % This function carries out a global optimization using MultiStart.
            %
            method = app.methodMenu.Value;
            planeSpeed = app.planeSpeedEdit.Value;
            npts = app.numPointsEdit.Value;
            
            options = optimoptions('fmincon');
            options.Algorithm = 'sqp';
            options.MaxFunctionEvaluations = npts*500;
            options.OptimalityTolerance = 5e-6; 
            options.StepTolerance = 5e-6;
            options.Display = 'off';
            options.UseParallel = false;
            
            % These are nominal initial conditions (straight line path)
            p0 = reshape(transpose(app.pInitial(2:end-1,:)),1,[]); % Make p0 a vector
            
            % Local copies of arguments are needed for setting up the parallel workers
            iX = app.iX; %#ok<ADPROPLC> 
            iY = app.iY; %#ok<ADPROPLC> 
            szX = app.szX; %#ok<ADPROPLC> 
            szY = app.szY; %#ok<ADPROPLC> 
            IntegrationFineness = app.IntegrationFineness; %#ok<ADPROPLC> 
            pInitial = app.pInitial; %#ok<ADPROPLC> 
            
            objfun = @(X)app.calculateTime(X,iX,iY,planeSpeed,szX,szY,method,...
                IntegrationFineness,pInitial,'optimizer'); %#ok<ADPROPLC> 
            
            problem = createOptimProblem('fmincon',...
                'objective', objfun,...
                'x0',p0(:),...
                'lb',0.001+p0*0, ...
                'ub',repmat([app.szX; app.szY],size(app.pInitial,1)-2,1),...
                'options',options);
            
            ms = MultiStart;
            ms.Display = 'iter';
            ms.UseParallel = true;
            
            % Sometimes annoying warnings show up during the optimization...            
            warning off;
            try 
                spmd
                    warning off;
                end
            catch
            end
            
            oldText = app.optimizeGlobalButton.Text;
            oldFontSize = app.optimizeGlobalButton.FontSize;
            app.optimizeGlobalButton.Text = 'Optimizing...';
            app.optimizeGlobalButton.FontSize = 8;
            delete(findall(app.axesMain,'tag','OptimalLine'));
            drawnow;

            popt = run(ms,problem,10); % Call MultiStart

            app.optimizeGlobalButton.Text = oldText;
            app.optimizeGlobalButton.FontSize = oldFontSize;
            
            p = [0 app.szY/2; reshape(popt, 2,[])'; app.szX app.szY/2]; % reshape the solution into Nx2
            
            % Plot the optimal curve and update the 'optimal' info box
            hold(app.axesMain,'on');
            XYdata = app.pointsToCurve(p,method,app.IntegrationFineness);
            app.lineOptimalCurve = plot(app.axesMain,XYdata(:,1),XYdata(:,2),'k','LineWidth',2,'tag','OptimalLine');
            set(app.lineOptimalCurve,'HitTest','off');
            
            hp = gobjects(size(p,1)-1,1);
            for n = 2:(size(p,1)-1)
                hp(n) = plot(app.axesMain,p(n,1),p(n,2),'ko');
            end
            set(hp(2:end),'tag','OptimalLine','HitTest','off');
            
            [t_opt,dist_opt] = ...
                app.calculateTime(XYdata,app.iX,app.iY,planeSpeed,app.szX,app.szY,method,...
                app.IntegrationFineness,app.pInitial,'interactive');
            updateOptText(app,dist_opt/t_opt,dist_opt,t_opt);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'PathOptimizationDemo';
            app.axesMain = uiaxes(app.figure1);
            app.panelRunControls = uipanel(app.figure1);
            app.methodMenu = uidropdown(app.panelRunControls);
            app.methodMenu.Items = {'linear', 'pchip', 'spline'};
            app.methodMenu.ItemsData = {'linear', 'pchip', 'spline'};
            app.methodMenu.ValueChangedFcn = createCallbackFcn(app, @methodMenu_Callback, true);
            app.methodMenu.Value = 'pchip';
            app.methodLabel = uilabel(app.panelRunControls);
            app.methodLabel.Text = 'Interpolation';
            app.startOptimPanel = uipanel(app.panelRunControls);
            app.startOptimPanel.Title = 'Start Optimization';
            app.optimizeGlobalButton = uibutton(app.startOptimPanel, 'push');
            app.optimizeGlobalButton.ButtonPushedFcn = createCallbackFcn(app, @optimizeGlobalButton_Callback, true);
            app.optimizeGlobalButton.Text = 'Global';
            app.optimizeLocalButton = uibutton(app.startOptimPanel, 'push');
            app.optimizeLocalButton.ButtonPushedFcn = createCallbackFcn(app, @optimizeLocalButton_Callback, true);
            app.optimizeLocalButton.Text = 'Local';
            app.AirspeedLabel = uilabel(app.panelRunControls);
            app.AirspeedLabel.Text = 'Airspeed';
            app.planeSpeedEdit = uieditfield(app.panelRunControls, 'numeric');
            app.planeSpeedEdit.Limits = [0 Inf];
            app.planeSpeedEdit.RoundFractionalValues = 'on';
            app.planeSpeedEdit.ValueChangedFcn = createCallbackFcn(app, @planeSpeedEdit_Callback, true);
            app.planeSpeedEdit.Value = 600;
            app.PointsLabel = uilabel(app.panelRunControls);
            app.PointsLabel.Text = 'Points';
            app.numPointsEdit = uieditfield(app.panelRunControls, 'numeric');
            app.numPointsEdit.Limits = [0 Inf];
            app.numPointsEdit.RoundFractionalValues = 'on';
            app.numPointsEdit.ValueChangedFcn = createCallbackFcn(app, @numPointsEdit_Callback, true);
            app.numPointsEdit.Value = 5;
            app.optimalResultsPanel = uipanel(app.figure1);
            app.optimalResultsPanel.Title = 'Optimal';
            app.optTimeText = uilabel(app.optimalResultsPanel);
            app.optTimeText.Text = 'Time:';
            app.optDistanceText = uilabel(app.optimalResultsPanel);
            app.optDistanceText.Text = 'Distance:';
            app.optSpeedText = uilabel(app.optimalResultsPanel);
            app.optSpeedText.Text = 'Average Speed:';
            app.manualResultsPanel = uipanel(app.figure1);
            app.manualResultsPanel.Title = 'Manual';
            app.manualSpeedText = uilabel(app.manualResultsPanel);
            app.manualSpeedText.Text = 'Average Speed:';
            app.manualDistanceText = uilabel(app.manualResultsPanel);
            app.manualDistanceText.Text = 'Distance:';
            app.manualTimeText = uilabel(app.manualResultsPanel);
            app.manualTimeText.Text = 'Time:';
            app.windFieldPanel = uipanel(app.figure1);
            app.wewWindFieldLabel = uibutton(app.windFieldPanel, 'push');
            app.wewWindFieldLabel.ButtonPushedFcn = createCallbackFcn(app, @newWindFieldLabel_Callback, true);
            app.wewWindFieldLabel.Text = 'New Wind Field';
            app.fieldSizeLabel = uilabel(app.windFieldPanel);
            app.fieldSizeLabel.Text = 'Field Size';
            app.RandomSeedLabel = uilabel(app.windFieldPanel);
            app.RandomSeedLabel.Text = 'Random Seed';
            app.seedEdit = uieditfield(app.windFieldPanel, 'numeric');
            app.seedEdit.Limits = [0 Inf];
            app.seedEdit.RoundFractionalValues = 'on';
            app.seedEdit.Value = 50;
            app.XLabel = uilabel(app.windFieldPanel);
            app.XLabel.Text = 'X';
            app.szXEdit = uieditfield(app.windFieldPanel, 'numeric');
            app.szXEdit.Limits = [10 300];
            app.szXEdit.RoundFractionalValues = 'on';
            app.szXEdit.Value = 100;
            app.YLabel = uilabel(app.windFieldPanel);
            app.YLabel.Text = 'Y';
            app.szYEdit = uieditfield(app.windFieldPanel, 'numeric');
            app.szYEdit.Limits = [10 300];
            app.szYEdit.RoundFractionalValues = 'on';
            app.szYEdit.Value = 50;
            app.WindFieldRoughnessLabel = uilabel(app.windFieldPanel);
            app.WindFieldRoughnessLabel.Text = {'Wind Field'; 'Roughness'};
            app.windFieldRoughnessEdit = uieditfield(app.windFieldPanel, 'numeric');
            app.windFieldRoughnessEdit.Limits = [0 1];
            app.windFieldRoughnessEdit.Value = 0.1;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PathOptimization_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @PathOptimizationDemo_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end